classdef EEGWISE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Switch_5                        matlab.ui.control.Switch
        CalculatingLampLabel_2          matlab.ui.control.Label
        ANALYZEButton                   matlab.ui.control.Button
        CalculatingLamp                 matlab.ui.control.Lamp
        Step4ArtifactrejectbyICAButtonGroup  matlab.ui.container.ButtonGroup
        MaxStepsEditField               matlab.ui.control.NumericEditField
        MaxStepsEditFieldLabel          matlab.ui.control.Label
        LREditField                     matlab.ui.control.NumericEditField
        LREditFieldLabel                matlab.ui.control.Label
        ICADropDown                     matlab.ui.control.DropDown
        ICADropDownLabel                matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        AutomaticVisualButton_3         matlab.ui.control.RadioButton
        AutomaticlabelingbyButton       matlab.ui.control.RadioButton
        EEGWISEEEGWorkflowImprovementandSignalEnhancementToolboxLabel  matlab.ui.control.Label
        Step3ChannelcorrectionButtonGroup  matlab.ui.container.ButtonGroup
        Label                           matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        SamplingrateHzEditFieldLabel_3  matlab.ui.control.Label
        AutomaticVisualButton           matlab.ui.control.RadioButton
        AutomaticbyKurtosisButton       matlab.ui.control.RadioButton
        Step1FilteringPanel             matlab.ui.container.Panel
        NotchHzDropDown                 matlab.ui.control.DropDown
        NotchHzDropDownLabel            matlab.ui.control.Label
        HighcutofffreqHzEditField       matlab.ui.control.NumericEditField
        HighcutofffreqHzEditFieldLabel  matlab.ui.control.Label
        LowcutofffreqHzEditField        matlab.ui.control.NumericEditField
        LowcutofffreqHzEditFieldLabel   matlab.ui.control.Label
        ResampingHzEditField            matlab.ui.control.NumericEditField
        ResampingHzEditFieldLabel       matlab.ui.control.Label
        InitializationPanel             matlab.ui.container.Panel
        Switch_4                        matlab.ui.control.Switch
        SamplingrateHzEditFieldLabel_4  matlab.ui.control.Label
        GenerateReportLabel             matlab.ui.control.Label
        GenerateReportSwitch            matlab.ui.control.Switch
        ResultdirectoryEditField        matlab.ui.control.EditField
        ResultdirectoryEditFieldLabel   matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        DatadirectoryEditField          matlab.ui.control.EditField
        DatadirectoryEditFieldLabel     matlab.ui.control.Label
        Step2EEGsegmentrejectionButtonGroup  matlab.ui.container.ButtonGroup
        ThresholdEditField_2            matlab.ui.control.EditField
        ThresholdLabel                  matlab.ui.control.Label
        AutomaticVisualButton_2         matlab.ui.control.RadioButton
        AutomaticbyASRButton            matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        selectedPath_data = ''; 
        selectedPath_results='';
        selectedPath_eeglab = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             app.selectedPath_data = uigetdir();
             if isfolder(app.selectedPath_data)
                app.DatadirectoryEditField.Value = [app.selectedPath_data '/'];
             end

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
             app.selectedPath_results = uigetdir();
             if isfolder(app.selectedPath_results)
                app.ResultdirectoryEditField.Value = [app.selectedPath_results '/'];
             end
        end

        % Button pushed function: ANALYZEButton
        function ANALYZEButtonPushed(app, event)
            app.CalculatingLampLabel_2.Text= 'Calculating';
            app.CalculatingLamp.Color = 'green';
            AnalyzeEEG(app); 
            app.CalculatingLamp.Color = 'red';
            app.CalculatingLampLabel_2.Text = 'Done';
        end

        % Callback function
        function BrowseButton_3Pushed(app, event)
            app.selectedPath_eeglab = uigetdir();
             if isfolder(app.selectedPath_eeglab)
                app.EEGLABdirectoryEditField.Value = [app.selectedPath_eeglab '/'];;
             end
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            if strcmp(value, 'Autopilot')
                app.Step2EEGsegmentrejectionButtonGroup.SelectedObject= app.AutomaticbyASRButton;
                app.Step3ChannelcorrectionButtonGroup.SelectedObject= app.AutomaticbyKurtosisButton;
                app.Step4ArtifactrejectbyICAButtonGroup.SelectedObject= app.AutomaticlabelingbyButton;
            else
                app.Step2EEGsegmentrejectionButtonGroup.SelectedObject= app.AutomaticVisualButton_2;
                app.Step3ChannelcorrectionButtonGroup.SelectedObject= app.AutomaticVisualButton;
                app.Step4ArtifactrejectbyICAButtonGroup.SelectedObject= app.AutomaticVisualButton_3;
            end
        end

        % Callback function
        function BrowseButton_3Pushed2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step2EEGsegmentrejectionButtonGroup = uibuttongroup(app.UIFigure);
            app.Step2EEGsegmentrejectionButtonGroup.Title = 'Step 2. EEG segment rejection';
            app.AutomaticbyASRButton = uiradiobutton(app.Step2EEGsegmentrejectionButtonGroup);
            app.AutomaticbyASRButton.Text = 'Automatic by ASR';
            app.AutomaticbyASRButton.Value = true;
            app.AutomaticVisualButton_2 = uiradiobutton(app.Step2EEGsegmentrejectionButtonGroup);
            app.AutomaticVisualButton_2.Text = 'Automatic + Visual';
            app.ThresholdLabel = uilabel(app.Step2EEGsegmentrejectionButtonGroup);
            app.ThresholdLabel.Text = '-- Threshold';
            app.ThresholdEditField_2 = uieditfield(app.Step2EEGsegmentrejectionButtonGroup, 'text');
            app.ThresholdEditField_2.Value = '5';
            app.InitializationPanel = uipanel(app.UIFigure);
            app.InitializationPanel.Title = 'Initialization';
            app.DatadirectoryEditFieldLabel = uilabel(app.InitializationPanel);
            app.DatadirectoryEditFieldLabel.Text = 'Data directory';
            app.DatadirectoryEditField = uieditfield(app.InitializationPanel, 'text');
            app.DatadirectoryEditField.Value = '../Data/';
            app.BrowseButton = uibutton(app.InitializationPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.InitializationPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ResultdirectoryEditFieldLabel = uilabel(app.InitializationPanel);
            app.ResultdirectoryEditFieldLabel.Text = 'Result directory';
            app.ResultdirectoryEditField = uieditfield(app.InitializationPanel, 'text');
            app.ResultdirectoryEditField.Value = '../Results/';
            app.GenerateReportSwitch = uiswitch(app.InitializationPanel, 'slider');
            app.GenerateReportSwitch.Items = {'Yes', 'No'};
            app.GenerateReportSwitch.Value = 'Yes';
            app.GenerateReportLabel = uilabel(app.InitializationPanel);
            app.GenerateReportLabel.Text = 'Generate report';
            app.SamplingrateHzEditFieldLabel_4 = uilabel(app.InitializationPanel);
            app.SamplingrateHzEditFieldLabel_4.Text = 'PSD Plot';
            app.Switch_4 = uiswitch(app.InitializationPanel, 'slider');
            app.Switch_4.Items = {'Yes', 'No'};
            app.Switch_4.Value = 'Yes';
            app.Step1FilteringPanel = uipanel(app.UIFigure);
            app.Step1FilteringPanel.Title = 'Step 1. Filtering';
            app.ResampingHzEditFieldLabel = uilabel(app.Step1FilteringPanel);
            app.ResampingHzEditFieldLabel.Text = 'Resamping (Hz)';
            app.ResampingHzEditField = uieditfield(app.Step1FilteringPanel, 'numeric');
            app.ResampingHzEditField.Value = 250;
            app.LowcutofffreqHzEditFieldLabel = uilabel(app.Step1FilteringPanel);
            app.LowcutofffreqHzEditFieldLabel.Text = 'Low cutoff freq (Hz)';
            app.LowcutofffreqHzEditField = uieditfield(app.Step1FilteringPanel, 'numeric');
            app.LowcutofffreqHzEditField.Value = 0.2;
            app.HighcutofffreqHzEditFieldLabel = uilabel(app.Step1FilteringPanel);
            app.HighcutofffreqHzEditFieldLabel.Text = 'High cutoff freq (Hz)';
            app.HighcutofffreqHzEditField = uieditfield(app.Step1FilteringPanel, 'numeric');
            app.HighcutofffreqHzEditField.Value = 30;
            app.NotchHzDropDownLabel = uilabel(app.Step1FilteringPanel);
            app.NotchHzDropDownLabel.Text = 'Notch (Hz)';
            app.NotchHzDropDown = uidropdown(app.Step1FilteringPanel);
            app.NotchHzDropDown.Items = {'60', '50'};
            app.NotchHzDropDown.ItemsData = [60 50];
            app.NotchHzDropDown.Value = 60;
            app.Step3ChannelcorrectionButtonGroup = uibuttongroup(app.UIFigure);
            app.Step3ChannelcorrectionButtonGroup.Title = 'Step 3. Channel correction';
            app.AutomaticbyKurtosisButton = uiradiobutton(app.Step3ChannelcorrectionButtonGroup);
            app.AutomaticbyKurtosisButton.Text = 'Automatic by Kurtosis ';
            app.AutomaticbyKurtosisButton.Value = true;
            app.AutomaticVisualButton = uiradiobutton(app.Step3ChannelcorrectionButtonGroup);
            app.AutomaticVisualButton.Text = 'Automatic + Visual';
            app.SamplingrateHzEditFieldLabel_3 = uilabel(app.Step3ChannelcorrectionButtonGroup);
            app.SamplingrateHzEditFieldLabel_3.Text = 'Spatial filtering by CAR';
            app.Switch_3 = uiswitch(app.Step3ChannelcorrectionButtonGroup, 'slider');
            app.Switch_3.Items = {'Yes', 'No'};
            app.Switch_3.Value = 'Yes';
            app.ThresholdEditFieldLabel = uilabel(app.Step3ChannelcorrectionButtonGroup);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Step3ChannelcorrectionButtonGroup, 'numeric');
            app.ThresholdEditField.Value = 5;
            app.Label = uilabel(app.Step3ChannelcorrectionButtonGroup);
            app.Label.Text = '--';
            app.EEGWISEEEGWorkflowImprovementandSignalEnhancementToolboxLabel = uilabel(app.UIFigure);
            app.EEGWISEEEGWorkflowImprovementandSignalEnhancementToolboxLabel.Text = 'EEGWISE - EEG Workflow Improvement and Signal Enhancement Toolbox';
            app.Step4ArtifactrejectbyICAButtonGroup = uibuttongroup(app.UIFigure);
            app.Step4ArtifactrejectbyICAButtonGroup.Title = 'Step 4. Artifact reject by ICA';
            app.AutomaticlabelingbyButton = uiradiobutton(app.Step4ArtifactrejectbyICAButtonGroup);
            app.AutomaticlabelingbyButton.Text = 'Automatic labeling by';
            app.AutomaticlabelingbyButton.Value = true;
            app.AutomaticVisualButton_3 = uiradiobutton(app.Step4ArtifactrejectbyICAButtonGroup);
            app.AutomaticVisualButton_3.Text = 'Automatic + Visual';
            app.DropDown = uidropdown(app.Step4ArtifactrejectbyICAButtonGroup);
            app.DropDown.Items = {'iMARA', 'MARA', 'ICLabels', 'Ensemble'};
            app.DropDown.Value = 'iMARA';
            app.ICADropDownLabel = uilabel(app.Step4ArtifactrejectbyICAButtonGroup);
            app.ICADropDownLabel.Text = 'ICA';
            app.ICADropDown = uidropdown(app.Step4ArtifactrejectbyICAButtonGroup);
            app.ICADropDown.Items = {'RUNICA', 'AMICA'};
            app.ICADropDown.Value = 'RUNICA';
            app.LREditFieldLabel = uilabel(app.Step4ArtifactrejectbyICAButtonGroup);
            app.LREditFieldLabel.Text = 'LR';
            app.LREditField = uieditfield(app.Step4ArtifactrejectbyICAButtonGroup, 'numeric');
            app.LREditField.Value = 0.0001;
            app.MaxStepsEditFieldLabel = uilabel(app.Step4ArtifactrejectbyICAButtonGroup);
            app.MaxStepsEditFieldLabel.Text = 'Max Steps';
            app.MaxStepsEditField = uieditfield(app.Step4ArtifactrejectbyICAButtonGroup, 'numeric');
            app.MaxStepsEditField.Value = 500;
            app.CalculatingLamp = uilamp(app.UIFigure);
            app.ANALYZEButton = uibutton(app.UIFigure, 'push');
            app.ANALYZEButton.ButtonPushedFcn = createCallbackFcn(app, @ANALYZEButtonPushed, true);
            app.ANALYZEButton.Text = 'ANALYZE';
            app.CalculatingLampLabel_2 = uilabel(app.UIFigure);
            app.CalculatingLampLabel_2.Text = '';
            app.Switch_5 = uiswitch(app.UIFigure, 'slider');
            app.Switch_5.Items = {'Autopilot', 'Hybrid'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = 'Autopilot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGWISE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end